classdef face_recg < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        EditField        matlab.ui.control.EditField
        Label_2          matlab.ui.control.Label
        Lamp             matlab.ui.control.Lamp
        Label            matlab.ui.control.Label
        loadfaceButton   matlab.ui.control.Button
        facedetedButton  matlab.ui.control.Button
        UIAxes_3         matlab.ui.control.UIAxes
        UIAxes_2         matlab.ui.control.UIAxes
        UIAxes           matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath(genpath('trainfunction'));        
        end

        % Button pushed function: facedetedButton
        function facedetedButtonPushed(app, event)
            load('C:\Users\22935\Desktop\face_recg(1) (2)\weights\weights.mat');
            load('C:\Users\22935\Desktop\face_recg(1) (2)\names\names.mat');
            %创建一个人脸检测器
            faceDetector = vision.CascadeObjectDetector(); 
            %调用笔记本摄像头，配置相关参数
            vidDevice = imaq.VideoDevice('winvideo', 1, 'YUY2_640x480', ...
                        'ROI', [1 1 640 480], ...
                        'ReturnedColorSpace', 'rgb' );
            %调节图像大小
            boxInserter = vision.ShapeInserter('BorderColor','Custom',...
                         'CustomBorderColor',[255 255 0]);
            %设置一个Frame用于创建死循环
            Frame =1;
           while Frame>0 
               %从摄像头的视频流中获取图像
               videoFrame = step(vidDevice);
               %放缩图像节省时间
               bbox = 4 * faceDetector.step(imresize(videoFrame, 1/4)); 
               %绘制人脸检测框
               videoOut = step(boxInserter, videoFrame, bbox); 
               %release(boxInserter);
               %展示人脸识别效果
               imshow(videoOut,"Parent",app.UIAxes);
               %读取bbox是否为空，判断是否截取出人脸并提取LBP特征
               flag=isempty(bbox);
               %如果不为空提取人脸，并提取特显示出来
               if flag==0
                   %裁剪出人脸
                   face_pic=imcrop(videoFrame,bbox);
                   %展示于第三个窗口
                   imshow(face_pic,"Parent",app.UIAxes_2);
                   %调节坐标轴，显示全图
                   axis(app.UIAxes_2, [0 size(face_pic, 2) 0 size(face_pic, 1)]);
                   %获取人脸colormap
                   mapping=getmapping(8,'u2');
                   %提取LBP特征
                   H1=lbp(face_pic,1,8,mapping,'h'); 
                   xline=1:length(H1);
                   %绘制LBP特征
                   plot(app.UIAxes_3,xline,H1,'-','Color',[rand(),rand(),rand()]);
                   a=imresize(face_pic,[120 120]);
                   a=rgb2gray(a);
                   c=a;
                   row=size(c,1);%读入图片，并对图片进行分块，2 
                   col=size(c,2);
                   B=mat2cell(c,[row/4 row/4 row/4 row/4],[col/4 col/4 col/4 col/4]);
                   H.a=0; 
                   for k=1:16
                       H1=lbp(B{k},1,8,mapping,'h'); %LBP histogram in (8,1) neighborhood %using uniform patterns
                       H.hist{k}=H1;
                   end   
                   train_x=[];%测试数据
                   hist=[H.hist{1},H.hist{2},H.hist{3},H.hist{4},H.hist{5},H.hist{6},H.hist{7},H.hist{8},H.hist{9},H.hist{10},H.hist{11},H.hist{12},H.hist{13},H.hist{14},H.hist{15},H.hist{16}];
                   MappedData = mapminmax(hist, 0, 0.5);
                   test_x = MappedData;
                   test_x  = double(test_x) ;
                   pred = predict(Theta1, Theta2,Theta3, test_x);
                   app.EditField.Value=names(pred);
               end
               %设置delay
               pause(0.1);
           end
        end

        % Button pushed function: loadfaceButton
        function loadfaceButtonPushed(app, event)
            %此处逻辑与facedetect相同
            faceDetector = vision.CascadeObjectDetector(); 
            vidDevice = imaq.VideoDevice('winvideo', 1, 'YUY2_640x480', ...
                        'ROI', [1 1 640 480], ...
                        'ReturnedColorSpace', 'rgb' );
           boxInserter = vision.ShapeInserter('BorderColor','Custom',...
                         'CustomBorderColor',[255 255 0]);
           Frame =1;
           %仅取出五张人脸用于训练模型
           while Frame<6
               videoFrame = step(vidDevice); 
               bbox = 4 * faceDetector.step(imresize(videoFrame, 1/4)); 
               videoOut = step(boxInserter, videoFrame, bbox); 
               imshow(videoOut,"Parent",app.UIAxes);
               flag=isempty(bbox);
               if flag==0
                   face_pic=imcrop(videoFrame,bbox);
                   imshow(face_pic,"Parent",app.UIAxes_2);
                   axis(app.UIAxes_2, [0 size(face_pic, 2) 0 size(face_pic, 1)]);
                   whichimg=num2str(Frame);
                   path=[whichimg,'.bmp'];
                   %保存人脸图像
                   imwrite(face_pic,path);
                   Frame=Frame+1;
               end
               pause(0.1);
           end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes_2 = uiaxes(app.UIFigure);
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.UIAxes_3 = uiaxes(app.UIFigure);
            app.UIAxes_3.XTick = [];
            app.UIAxes_3.YTick = [];
            app.facedetedButton = uibutton(app.UIFigure, 'push');
            app.facedetedButton.ButtonPushedFcn = createCallbackFcn(app, @facedetedButtonPushed, true);
            app.facedetedButton.Text = '开始检测人脸';
            app.loadfaceButton = uibutton(app.UIFigure, 'push');
            app.loadfaceButton.ButtonPushedFcn = createCallbackFcn(app, @loadfaceButtonPushed, true);
            app.loadfaceButton.Text = '导入新的人脸';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '检测状态';
            app.Lamp = uilamp(app.UIFigure);
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '人名';
            app.EditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = face_recg

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end